classdef pokeri_kello < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        secondsEditField             matlab.ui.control.NumericEditField
        secondsEditFieldLabel        matlab.ui.control.Label
        minutesEditField             matlab.ui.control.NumericEditField
        minutesEditFieldLabel        matlab.ui.control.Label
        BigBlindGauge                matlab.ui.control.LinearGauge
        BigBlindGaugeLabel           matlab.ui.control.Label
        TimerGauge                   matlab.ui.control.SemicircularGauge
        TimerGaugeLabel              matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        EditFieldLabel               matlab.ui.control.Label
        LeveltimeEditField           matlab.ui.control.NumericEditField
        LeveltimeEditFieldLabel      matlab.ui.control.Label
        BBEditField                  matlab.ui.control.NumericEditField
        BBEditFieldLabel             matlab.ui.control.Label
        StartingstackEditField       matlab.ui.control.NumericEditField
        StartingstackEditFieldLabel  matlab.ui.control.Label
        Switch                       matlab.ui.control.ToggleSwitch
        SwitchLabel                  matlab.ui.control.Label
        Button                       matlab.ui.control.Button
    end

    
    properties (Access = public)
        
        StartingStack = 5000;
        BB = 100;
        Level_time = 20;
        clock_set = 0;
        i = 1;
        
        minutes = 0;
        seconds = 59;

        
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            while app.Switch.Value == "On"
                for x = app.minutes:-1:0
                    app.minutesEditField.Value = x;
                    for y = app.seconds:-1:0
                        while app.Switch.Value == "Off"
                            app.minutes = x;
                            app.seconds = y;
                            pause(1);
                        end
                        app.secondsEditField.Value = y;
                        pause(1)
                    end

                    if y == 0
                        app.seconds = 59;
                        if x == 0

                            sound(1);
                            app.BigBlindGauge.Value = app.BigBlindGauge.Value + 50;                           
                        end
                    end
                end
            end     
        end

        % Value changed function: BBEditField
        function BBEditFieldValueChanged(app, event)
            app.BB = app.BBEditField.Value;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           app.BigBlindGauge.Value = app.BBEditField.Value;

           app.seconds = 59;
           app.Level_time = app.LeveltimeEditField.Value;
           
           app.minutes = app.LeveltimeEditField.Value-1;
           app.minutesEditField.Value = app.LeveltimeEditField.Value-1;
           app.secondsEditField.Value = app.seconds;
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.StartingstackEditFieldLabel = uilabel(app.UIFigure);
            app.StartingstackEditFieldLabel.Text = 'Starting stack';
            app.StartingstackEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingstackEditField.Value = 5000;
            app.BBEditFieldLabel = uilabel(app.UIFigure);
            app.BBEditFieldLabel.Text = {'BB'; ''};
            app.BBEditField = uieditfield(app.UIFigure, 'numeric');
            app.BBEditField.ValueChangedFcn = createCallbackFcn(app, @BBEditFieldValueChanged, true);
            app.BBEditField.Value = 50;
            app.LeveltimeEditFieldLabel = uilabel(app.UIFigure);
            app.LeveltimeEditFieldLabel.Text = {'Level time'; ''};
            app.LeveltimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeveltimeEditField.Value = 20;
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.TimerGaugeLabel = uilabel(app.UIFigure);
            app.TimerGaugeLabel.Text = 'Timer';
            app.TimerGauge = uigauge(app.UIFigure, 'semicircular');
            app.TimerGauge.Limits = [0 20];
            app.BigBlindGaugeLabel = uilabel(app.UIFigure);
            app.BigBlindGaugeLabel.Text = 'Big Blind';
            app.BigBlindGauge = uigauge(app.UIFigure, 'linear');
            app.BigBlindGauge.Limits = [0 500];
            app.BigBlindGauge.MajorTicks = [0 50 100 150 200 250 300 350 400 450 500 550 600];
            app.BigBlindGauge.MajorTickLabels = {'0', '50', '100', '150', '200', '300', '400', '500', '600', '800', '1000', '1200', '1600'};
            app.BigBlindGauge.MinorTicks = [];
            app.minutesEditFieldLabel = uilabel(app.UIFigure);
            app.minutesEditFieldLabel.Text = 'minutes';
            app.minutesEditField = uieditfield(app.UIFigure, 'numeric');
            app.secondsEditFieldLabel = uilabel(app.UIFigure);
            app.secondsEditFieldLabel.Text = 'seconds';
            app.secondsEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pokeri_kello

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end